classdef boneFracture < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DetectCrackButton           matlab.ui.control.Button
        LoadImageButton             matlab.ui.control.Button
        BONEFRACTUREDETECTIONLabel  matlab.ui.control.Label
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        open_frac_img;
        tranv_frac_img;

    end
    
    methods (Access = private)
        
        function results = openFrac(app)
            img = app.open_frac_img;
            grayimg = rgb2gray(img);
            grayimg = medfilt2(grayimg,[5 5]);
            binImg = imbinarize(grayimg,"global");
            dilImg = bwmorph(binImg,"dilate",5);
            shrink = bwmorph(dilImg,"shrink",5);
            filled_shrink = imfill(shrink,"holes");
            crack = xor(filled_shrink,shrink);
            crack = bwmorph(crack,'shrink',5);
            [r,c] = find(crack == 1);
            mid_idx = floor(length(r)/2);
            mid_point = [r(mid_idx), c(mid_idx)];
            imshow(img,'Parent',app.UIAxes_2);
            images.roi.Rectangle(app.UIAxes_2,'Position', ...
                [mid_point(2)-20,mid_point(1)+10,50,50],'Color','r');
        end
        
        function results = transFrac(app)
            I = app.tranv_frac_img;
            I = rgb2gray(I);

            lap = fspecial('sobel');
            BW = imfilter(I,lap,"replicate");
            BW = imadjust(BW,[0 1],[0 1],0.5);

            [H,theta,rho] = hough(BW);
            P = houghpeaks(H,5,'threshold',ceil(0.3*max(H(:))));
            lines = houghlines(BW,theta,rho,P,'FillGap',10,'MinLength',10);

            max_len = 0;
            ax0 = app.UIAxes;
            hold(ax0,"on")
            for k = 1:length(lines)
                xy = [lines(k).point1; lines(k).point2];
                plot(ax0,xy(:,1),xy(:,2),'LineWidth',2,'Color','green');

                % Plot beginnings and ends of lines
                plot(ax0,xy(1,1),xy(1,2),'x','LineWidth',2,'Color','yellow');
                plot(ax0,xy(2,1),xy(2,2),'x','LineWidth',2,'Color','red');

                % Determine the endpoints of the longest line segment
                len = norm(lines(k).point1 - lines(k).point2);
                if ( len > max_len)
                    max_len = len;
                    xy_long = xy;
                end
            end
            hold(ax0,'off')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            app.open_frac_img = imread('images.jpg');
            app.tranv_frac_img = imread('img1.jpeg.jpg');

            imshow(app.open_frac_img,'Parent',app.UIAxes_2);
            imshow(app.tranv_frac_img,'Parent',app.UIAxes);
        end

        % Button pushed function: DetectCrackButton
        function DetectCrackButtonPushed(app, event)
            openFrac(app)
            transFrac(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Transverse Fracture')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Open Fracture')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.BONEFRACTUREDETECTIONLabel = uilabel(app.UIFigure);
            app.BONEFRACTUREDETECTIONLabel.Text = 'BONE FRACTURE DETECTION';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.DetectCrackButton = uibutton(app.UIFigure, 'push');
            app.DetectCrackButton.ButtonPushedFcn = createCallbackFcn(app, @DetectCrackButtonPushed, true);
            app.DetectCrackButton.Text = 'Detect Crack';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = boneFracture

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end